classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResumeButton                   matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        TimeLabel                      matlab.ui.control.Label
        SeverityLabel                  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        CodeBlueLabel                  matlab.ui.control.TextArea
        lueTextAreaLabel               matlab.ui.control.Label
        FaultTextArea                  matlab.ui.control.TextArea
        SensorStatusTextAreaLabel      matlab.ui.control.Label
        RecommendationTextArea         matlab.ui.control.TextArea
        RecommendationTextAreaLabel    matlab.ui.control.Label
        RiskLabel                      matlab.ui.control.Label
        RREditField                    matlab.ui.control.NumericEditField
        RespiratoryRateEditFieldLabel  matlab.ui.control.Label
        TempEditField                  matlab.ui.control.NumericEditField
        TemperatureLabel               matlab.ui.control.Label
        BPEditField                    matlab.ui.control.NumericEditField
        BPEditFieldLabel               matlab.ui.control.Label
        SpO2EditField                  matlab.ui.control.NumericEditField
        SpO2EditFieldLabel             matlab.ui.control.Label
        HREditField                    matlab.ui.control.NumericEditField
        HeartRatebpmEditFieldLabel     matlab.ui.control.Label
        TrendAxes                      matlab.ui.control.UIAxes
        ECGAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isPaused = true; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = linspace(0,10,1000);

            ecg = zeros(size(t));

            for beat = 1:10
                qrs = 1.2 * exp(-((t - beat).^2)/0.002);
                p   = 0.15 * exp(-((t - (beat-0.2)).^2)/0.01);
                tw  = 0.25 * exp(-((t - (beat+0.25)).^2)/0.03);

                ecg = ecg + p + qrs + tw;
            end

            ecg = ecg + 0.02*randn(size(t));

            plot(app.ECGAxes,t,ecg);
            title(app.ECGAxes,'Real - Time ECG Live Monitor');
            ylim(app.ECGAxes,[-0.5 1.8]);

            for k = 1:200
                while app.isPaused
                    pause(0.1);
                    drawnow;
                end
               

                % Simulated vitals (realistic normal patient)
                hr = 78 + 12*sin(0.08*k) + randn*3;
                spo2 = 97 + 1.5*sin(0.06*k) + randn*0.5;
                bp = 118 + 15*sin(0.04*k) + randn*4;
                temp = 36.9 + 0.5*sin(0.03*k) + randn*0.1;
                rr = 16 + 3*sin(0.07*k) + randn*1;

                % Emergency event demo
                if k > 120 && k < 135
                    spo2 = 88;
                    hr = 135;
                    bp = 85;
                    temp = 38.8;
                    rr = 26;
                end 
    % Update vitals
    app.HREditField.Value = round(hr);
    app.SpO2EditField.Value = round(spo2);
    app.BPEditField.Value = round(bp);
    app.TempEditField.Value = round(temp,1);
    app.RREditField.Value = round(rr);

    % SYSTEM TIME
    app.TimeLabel.Text = ['System Time: ' char(datetime('now','Format','HH:mm:ss'))];

    % Severity calculation
    severity = 0;

    if hr > 95 || hr < 60
        severity = severity + 25;
    end

    if spo2 < 96
        severity = severity + 30;
    end

    if bp < 95 || bp > 150
        severity = severity + 20;
    end

    if temp > 37.8 || temp < 35.8
        severity = severity + 15;
    end

    if rr > 20 || rr < 12
        severity = severity + 10;
    end

    app.SeverityLabel.Text = ...
        ['PATIENT SEVERITY SCORE : ' num2str(severity) ' / 100'];

    % ECG animation
    ecg = zeros(size(t));
    shift = mod(k,10)/10;

    for beat = 1:10
        qrs = 1.2 * exp(-((t - (beat+shift)).^2)/0.002);
        p   = 0.15 * exp(-((t - (beat+shift-0.2)).^2)/0.01);
        tw  = 0.25 * exp(-((t - (beat+shift+0.25)).^2)/0.03);

        ecg = ecg + p + qrs + tw;
    end

    ecg = ecg + 0.02*randn(size(t));

    plot(app.ECGAxes,t,ecg);
    title(app.ECGAxes,'Real - Time ECG Live Monitor');
    ylim(app.ECGAxes,[-0.5 1.8]);

    % Trend graph
    trend = 95 + 2*sin(0.1*(1:k)) + 0.2*randn(1,k);
    plot(app.TrendAxes,1:k,trend);
    title(app.TrendAxes,'Patient Trend Forecast');

    % RISK LOGIC
    if severity >= 70

        app.RiskLabel.Text = 'RISK STATUS : CRITICAL';
        app.RiskLabel.FontColor = [1 0 0];

        app.UIFigure.Color = [0.25 0 0];   % flash red background

        app.RecommendationTextArea.Value = {'Immediate intervention required'};
        app.CodeBlueLabel.Value = 'CODE BLUE';
        app.CodeBlueLabel.FontColor = [1 0 0];

        app.FaultTextArea.Value = {'Critical patient condition'};

        beep
        pause(0.1)

        app.CodeBlueLabel.FontColor = [1 1 1];
        pause(0.1)

        app.CodeBlueLabel.FontColor = [1 0 0];
        app.UIFigure.Color = [0.1 0.1 0.1];   % restore dark background

    elseif severity >= 30

        app.RiskLabel.Text = 'RISK STATUS : MEDIUM';
        app.RiskLabel.FontColor = [1 0.6 0];

        app.RecommendationTextArea.Value = {'Monitor patient closely'};
        app.CodeBlueLabel.Value = 'ALERT';
        app.CodeBlueLabel.FontColor = [1 0.6 0];

        if rand > 0.85
            app.FaultTextArea.Value = {'Sensor anomaly detected'};
        else
            app.FaultTextArea.Value = {'No sensor fault'};
        end

    else

        app.RiskLabel.Text = 'RISK STATUS : LOW';
        app.RiskLabel.FontColor = [1 1 1];

        app.RecommendationTextArea.Value = {'Patient stable'};
        app.CodeBlueLabel.Value = 'STABLE';
        app.CodeBlueLabel.FontColor = [1 1 1];

        app.FaultTextArea.Value = {'No sensor fault'};
    end

    drawnow;
    pause(0.2);

            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.isPaused = true;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.isPaused = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.isPaused = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ECGAxes = uiaxes(app.UIFigure);
            title(app.ECGAxes, {'Real - Time '; 'ECG Live Monitor'})
            zlabel(app.ECGAxes, 'Z')
            app.TrendAxes = uiaxes(app.UIFigure);
            title(app.TrendAxes, 'Patient Trend Forecast')
            zlabel(app.TrendAxes, 'Z')
            app.HeartRatebpmEditFieldLabel = uilabel(app.UIFigure);
            app.HeartRatebpmEditFieldLabel.Text = 'Heart Rate (bpm)';
            app.HREditField = uieditfield(app.UIFigure, 'numeric');
            app.SpO2EditFieldLabel = uilabel(app.UIFigure);
            app.SpO2EditFieldLabel.Text = 'SpO2 (%)';
            app.SpO2EditField = uieditfield(app.UIFigure, 'numeric');
            app.BPEditFieldLabel = uilabel(app.UIFigure);
            app.BPEditFieldLabel.Text = 'Blood Pressure(mmHg)';
            app.BPEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature (C)';
            app.TempEditField = uieditfield(app.UIFigure, 'numeric');
            app.RespiratoryRateEditFieldLabel = uilabel(app.UIFigure);
            app.RespiratoryRateEditFieldLabel.Text = 'Respiratory Rate';
            app.RREditField = uieditfield(app.UIFigure, 'numeric');
            app.RiskLabel = uilabel(app.UIFigure);
            app.RiskLabel.Text = 'RISK STATUS : LOW';
            app.RecommendationTextAreaLabel = uilabel(app.UIFigure);
            app.RecommendationTextAreaLabel.Text = 'Clinical Recommendation';
            app.RecommendationTextArea = uitextarea(app.UIFigure);
            app.RecommendationTextArea.Value = {'Patient stable'};
            app.SensorStatusTextAreaLabel = uilabel(app.UIFigure);
            app.SensorStatusTextAreaLabel.Text = 'Sensor Status';
            app.FaultTextArea = uitextarea(app.UIFigure);
            app.FaultTextArea.Value = {'No sensor fault'; ''};
            app.lueTextAreaLabel = uilabel(app.UIFigure);
            app.lueTextAreaLabel.Text = 'Emergency Alert';
            app.CodeBlueLabel = uitextarea(app.UIFigure);
            app.CodeBlueLabel.Value = {'STABLE'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'AI - Enhanced ICU Patient Monitoring Dashboard';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Clinical Decision Support System';
            app.SeverityLabel = uilabel(app.UIFigure);
            app.SeverityLabel.Text = 'PARIENT SEVERITY SCORE : 0/100';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'System Time: --';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start button';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end